classdef cellBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        DropDownCells  matlab.ui.control.DropDown
        Panel_2        matlab.ui.container.Panel
        Panel_3        matlab.ui.container.Panel
        Panel_1        matlab.ui.container.Panel
    end

    
    properties (Access = private)
        hvwr1
        hvwr2
        hvwr3
        cellT
        paths
    end
    
    methods (Access = private)
        
        function updateDisplay(app)
           
            
            app.hvwr1 = viewer3d(BackgroundGradient="off",Parent=app.Panel_1);
            app.hvwr2 = viewer3d(BackgroundGradient="off",Parent=app.Panel_2);
            app.hvwr3 = viewer3d(BackgroundGradient="off",Parent=app.Panel_3);

            object = app.DropDownCells.Value;

            contents = dir(fullfile(app.paths.cells,object+"*.obj*"));

            for n=1:3
                % clear(app.("Panel_"+n).Children)
                fullpath = fullfile(app.paths.cells,contents(n).name);
                mesh = readSurfaceMesh(fullpath);
                % hvwr(n) = viewer3d(BackgroundGradient="off",Parent=app.("Panel_"+n));
                surfaceMeshShow(mesh,Title=contents(n).name,Parent=app.("hvwr"+n))
            end

            % linkprop(hvwr,{'CameraPosition','CameraZoom'})
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, cellT, paths)

            app.cellT = cellT;
            app.paths = paths;

            app.DropDownCells.Items = cellT.Object;
            app.DropDownCells.Value = cellT.Object(1);

            updateDisplay(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel_1 = uipanel(app.GridLayout);
            app.Panel_3 = uipanel(app.GridLayout);
            app.Panel_2 = uipanel(app.GridLayout);
            app.DropDownCells = uidropdown(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellBrowser(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end